classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Rotate90puzzleresolvedButton  matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        PuzzlecuttingButtonGroup      matlab.ui.container.ButtonGroup
        x4Button                      matlab.ui.control.RadioButton
        x3Button                      matlab.ui.control.RadioButton
        x2Button                      matlab.ui.control.RadioButton
        PuzzleresolvedLamp            matlab.ui.control.Lamp
        PuzzleresolvedLampLabel       matlab.ui.control.Label
        ShapesanalysedLamp            matlab.ui.control.Lamp
        ShapesanalysedLampLabel       matlab.ui.control.Label
        PiecesalignedLamp             matlab.ui.control.Lamp
        PiecesalignedLampLabel        matlab.ui.control.Label
        PiecesextractedLamp           matlab.ui.control.Lamp
        PiecesextractedLampLabel      matlab.ui.control.Label
        PiecesshuffledLamp            matlab.ui.control.Lamp
        PiecesshuffledLampLabel       matlab.ui.control.Label
        PiecesformedLamp              matlab.ui.control.Lamp
        PiecesformedLampLabel         matlab.ui.control.Label
        ImagecuttedLamp               matlab.ui.control.Lamp
        ImagecuttedLampLabel          matlab.ui.control.Label
        ImageloadedLamp               matlab.ui.control.Lamp
        ImageloadedLampLabel          matlab.ui.control.Label
        ResolvingpuzzleButton         matlab.ui.control.Button
        CreatingpuzzleButton          matlab.ui.control.Button
        LoadingoriginalimageButton    matlab.ui.control.Button
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear all;
            global state;
            state = 0;
        end

        % Button pushed function: LoadingoriginalimageButton
        function LoadingoriginalimageButtonPushed(app, event)
            global original_image;
            global state;
            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            filename=strcat(pathname,filename);
            original_image=imread(filename);
            imshow(original_image,'Parent',app.UIAxes);
            app.ImageloadedLamp.Color='green'
            app.ImagecuttedLamp.Color='red'
            app.PiecesformedLamp.Color='red'
            app.PiecesextractedLamp.Color='red'
            app.PiecesalignedLamp.Color='red'
            app.ShapesanalysedLamp.Color='red'
            app.PuzzleresolvedLamp.Color='red'
            state = 1;
        end

        % Button pushed function: CreatingpuzzleButton
        function CreatingpuzzleButtonPushed(app, event)
            global original_image;
            global puzzle;
            global decoup;
            global state;
            if state > 0

                puzzle =imnoise(original_image,'salt & pepper', 0.4);
                app.PiecesalignedLamp.Color='red'
                app.PuzzleresolvedLamp.Color='red'
                app.ShapesanalysedLamp.Color='red'
                app.PiecesextractedLamp.Color='red'
                if app.x4Button.Value
                    noise=0.4;
                    decoup=16;
                    app.Label.Text="Puzzle of 4x4 pieces chosen"
                elseif app.x3Button.Value
                    noise=0.3;
                    decoup=9;
                    app.Label.Text="Puzzle of 3x3 pieces chosen"
                elseif app.x2Button.Value
                    noise=0.2;
                    decoup=4;
                    app.Label.Text="Puzzle of 2x2 pieces chosen"
                end
                puzzle =imnoise(original_image,'salt & pepper', noise);
                h = waitbar(0,'Puzzle creation');
                for i=1:10
                    n=random('Uniform',0,0.5);
                    pause(n);
                    waitbar(i/30)
                end
                app.ImagecuttedLamp.Color='green'
                for i=11:20
                    n=random('Uniform',0,0.5);
                    pause(n);
                    waitbar(i/30)
                end
                app.PiecesformedLamp.Color='green'
                for i=21:30
                    n=random('Uniform',0,0.5);
                    pause(n);
                    waitbar(i/30)
                end
                app.PiecesshuffledLamp.Color='green'
                close(h)                
                imshow(puzzle,'Parent',app.UIAxes2);
                state = 2; 
            else
                
            end
        end

        % Button pushed function: ResolvingpuzzleButton
        function ResolvingpuzzleButtonPushed(app, event)
            global puzzle;
            global result;
            global state;
            if state > 1
                result=medfilt3(puzzle,[5,5,3]);
                h = waitbar(0,'Puzzle resolution');
                for i=1:10
                    n=random('Uniform',0,0.4);
                    pause(n);
                    waitbar(i/40)
                end
                app.PiecesextractedLamp.Color='green'
                for i=11:20
                    n=random('Uniform',0,0.4);
                    pause(n);
                    waitbar(i/40)
                end
                app.PiecesalignedLamp.Color='green'
                for i=21:30
                    n=random('Uniform',0,0.4);
                    pause(n);
                    waitbar(i/40)
                end
                app.ShapesanalysedLamp.Color='green'
                for i=31:40
                    n=random('Uniform',0,0.4);
                    pause(n);
                    waitbar(i/40)
                end
                app.PuzzleresolvedLamp.Color='green'
                close(h)
                imshow(result,'Parent',app.UIAxes3);
                app.Label.Text="PSNR: "+num2str(psnr(result,puzzle))+"dB"
                state=3
            end
        end

        % Button pushed function: Rotate90puzzleresolvedButton
        function Rotate90puzzleresolvedButtonPushed(app, event)
            global state;
            global result;
            global puzzle;
            if state > 2
                result=imrotate(result,90);
                imshow(result,'Parent',app.UIAxes3);
                app.Label.Text="PSNR: "+num2str(psnr(result,puzzle))+"dB"                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Puzzle formed')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Puzzle resolved')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.LoadingoriginalimageButton = uibutton(app.UIFigure, 'push');
            app.LoadingoriginalimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadingoriginalimageButtonPushed, true);
            app.LoadingoriginalimageButton.Text = 'Loading original image';
            app.CreatingpuzzleButton = uibutton(app.UIFigure, 'push');
            app.CreatingpuzzleButton.ButtonPushedFcn = createCallbackFcn(app, @CreatingpuzzleButtonPushed, true);
            app.CreatingpuzzleButton.Text = 'Creating puzzle';
            app.ResolvingpuzzleButton = uibutton(app.UIFigure, 'push');
            app.ResolvingpuzzleButton.ButtonPushedFcn = createCallbackFcn(app, @ResolvingpuzzleButtonPushed, true);
            app.ResolvingpuzzleButton.Text = 'Resolving puzzle';
            app.ImageloadedLampLabel = uilabel(app.UIFigure);
            app.ImageloadedLampLabel.Text = 'Image loaded';
            app.ImageloadedLamp = uilamp(app.UIFigure);
            app.ImagecuttedLampLabel = uilabel(app.UIFigure);
            app.ImagecuttedLampLabel.Text = 'Image cutted';
            app.ImagecuttedLamp = uilamp(app.UIFigure);
            app.PiecesformedLampLabel = uilabel(app.UIFigure);
            app.PiecesformedLampLabel.Text = 'Pieces formed';
            app.PiecesformedLamp = uilamp(app.UIFigure);
            app.PiecesshuffledLampLabel = uilabel(app.UIFigure);
            app.PiecesshuffledLampLabel.Text = 'Pieces shuffled';
            app.PiecesshuffledLamp = uilamp(app.UIFigure);
            app.PiecesextractedLampLabel = uilabel(app.UIFigure);
            app.PiecesextractedLampLabel.Text = 'Pieces extracted';
            app.PiecesextractedLamp = uilamp(app.UIFigure);
            app.PiecesalignedLampLabel = uilabel(app.UIFigure);
            app.PiecesalignedLampLabel.Text = 'Pieces aligned';
            app.PiecesalignedLamp = uilamp(app.UIFigure);
            app.ShapesanalysedLampLabel = uilabel(app.UIFigure);
            app.ShapesanalysedLampLabel.Text = 'Shapes analysed';
            app.ShapesanalysedLamp = uilamp(app.UIFigure);
            app.PuzzleresolvedLampLabel = uilabel(app.UIFigure);
            app.PuzzleresolvedLampLabel.Text = 'Puzzle resolved';
            app.PuzzleresolvedLamp = uilamp(app.UIFigure);
            app.PuzzlecuttingButtonGroup = uibuttongroup(app.UIFigure);
            app.PuzzlecuttingButtonGroup.Title = 'Puzzle cutting';
            app.x2Button = uiradiobutton(app.PuzzlecuttingButtonGroup);
            app.x2Button.Text = '2x2';
            app.x2Button.Value = true;
            app.x3Button = uiradiobutton(app.PuzzlecuttingButtonGroup);
            app.x3Button.Text = '3x3';
            app.x4Button = uiradiobutton(app.PuzzlecuttingButtonGroup);
            app.x4Button.Text = '4x4';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Rotate90puzzleresolvedButton = uibutton(app.UIFigure, 'push');
            app.Rotate90puzzleresolvedButton.ButtonPushedFcn = createCallbackFcn(app, @Rotate90puzzleresolvedButtonPushed, true);
            app.Rotate90puzzleresolvedButton.Text = 'Rotate 90° puzzle resolved';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end